classdef GoldGuesser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PercentCount         matlab.ui.control.Label
        SuccessCount         matlab.ui.control.Label
        AttemptsCount        matlab.ui.control.Label
        ImageLeprechaun      matlab.ui.control.Image
        imageGold            matlab.ui.control.Image
        Button_3             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        PercentCorrectLabel  matlab.ui.control.Label
        SuccessLabel         matlab.ui.control.Label
        AttemptsLabel        matlab.ui.control.Label
        Button               matlab.ui.control.Button
    end

    
    properties (Access = private)
        LeprechaunPositions
        GoldPositions
        LeprechaunImageSources

        attempts = 0
        successes = 0
    end
    
    methods (Access = private)
        
        function makeAGuess(app, leprechaunIndex) %first argument of method has to be app
           app.attempts = app.AttemptsCount + 1;
           app.ImageLeprechaun.Position = app.LeprechaunPositions(leprechaunIndex,:);
           goldIndex = randi([1,3]);
           app.imageGold.Position = app.GoldPositions(goldIndex,:);
           if goldIndex == leprechaunIndex
              app.ImageLeprechaun.ImageSource = app.LeprechaunImageSources(1);
              app.successes = app.SuccessCount + 1;
           else
              app.ImageLeprechaun.ImageSource = app.LeprechaunImageSources(2);
           end

            app.AttemptsLabel.Text = num2str(app.attempts);
            app.SuccessLabel.Text = num2str(app.successes);
            app.PercentCorrectLabel = num2str((app.successes / app.attempts) * 100, 0);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.LeprechaunImageSources = ["./leprechaun_happy.png","./leprechaun_sad.png"]; %use app. to reference an instance variable inside the class

            app.LeprechaunPositions(1, :) = [76,300,104,90];
            app.LeprechaunPositions(2,:) = [270,300,104,90];
            app.LeprechaunPositions(3,:) = [473,300,104,90];

            app.GoldPositions(1,:) = [5,1,247,162];
            app.GoldPositions(2,:) = [199,1,247,162];
            app.GoldPositions(3,:) = [402,1,247,162];

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.ImageLeprechaun.ImageSource = app.LeprechaunImageSources(1);
            app.ImageLeprechaun.Position = app.LeprechaunPositions(1,:);
            app.makeAGuess(1);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.ImageLeprechaun.ImageSource = app.LeprechaunImageSources(2);
            app.ImageLeprechaun.Position = app.LeprechaunPositions(2,:);
            app.makeAGuess(2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.ImageLeprechaun.ImageSource = app.LeprechaunImageSources(2);
            app.ImageLeprechaun.Position = app.LeprechaunPositions(3,:);
            app.makeAGuess(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'clover.png');
            app.Button.Text = '';
            app.AttemptsLabel = uilabel(app.UIFigure);
            app.AttemptsLabel.Text = 'Attempts:';
            app.SuccessLabel = uilabel(app.UIFigure);
            app.SuccessLabel.Text = 'Success:';
            app.PercentCorrectLabel = uilabel(app.UIFigure);
            app.PercentCorrectLabel.Text = 'Percent Correct:';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'clover.png');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'clover.png');
            app.Button_3.Text = '';
            app.imageGold = uiimage(app.UIFigure);
            app.imageGold.ImageSource = fullfile(pathToMLAPP, 'scattered_coins.png');
            app.ImageLeprechaun = uiimage(app.UIFigure);
            app.ImageLeprechaun.ImageSource = fullfile(pathToMLAPP, 'leprechaun_happy.png');
            app.AttemptsCount = uilabel(app.UIFigure);
            app.AttemptsCount.Text = '0';
            app.SuccessCount = uilabel(app.UIFigure);
            app.SuccessCount.Text = '0';
            app.PercentCount = uilabel(app.UIFigure);
            app.PercentCount.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoldGuesser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end