classdef LEDOnOff < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LEDOnOffUIFigure  matlab.ui.Figure
        OffButton         matlab.ui.control.Button
        OnButton          matlab.ui.control.Button
        LEDOnOffLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        SerialObj
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.SerialObj = serialport("COM4", 19200, "Timeout", 15);
        end

        % Button pushed function: OnButton
        function OnButtonPushed(app, event)
            fprintf("You pressed the On Button.\n");

            flush(app.SerialObj)
            writeline(app.SerialObj, "LED ON");
            response = readline(app.SerialObj)

        end

        % Button pushed function: OffButton
        function OffButtonPushed(app, event)
            fprintf("You pressed the Off Button.\n")

            flush(app.SerialObj)
            writeline(app.SerialObj, "LED OFF");
            response = readline(app.SerialObj)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.LEDOnOffUIFigure = uifigure('Visible', 'off');
            app.LEDOnOffUIFigure.Name = 'LEDOnOff';
            app.LEDOnOffLabel = uilabel(app.LEDOnOffUIFigure);
            app.LEDOnOffLabel.Text = 'LED On/Off';
            app.OnButton = uibutton(app.LEDOnOffUIFigure, 'push');
            app.OnButton.ButtonPushedFcn = createCallbackFcn(app, @OnButtonPushed, true);
            app.OnButton.Icon = fullfile(pathToMLAPP, 'happycat.jpg');
            app.OnButton.Text = '';
            app.OffButton = uibutton(app.LEDOnOffUIFigure, 'push');
            app.OffButton.ButtonPushedFcn = createCallbackFcn(app, @OffButtonPushed, true);
            app.OffButton.Icon = fullfile(pathToMLAPP, '360_F_177436300_PN50VtrZbrdxSAMKIgbbOIU90ZSCn8y3.jpg');
            app.OffButton.Text = '';
            % Show the figure after all components are created
            app.LEDOnOffUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LEDOnOff

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LEDOnOffUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LEDOnOffUIFigure)
        end
    end
end