classdef AdeeptGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SavedPositionsPanel           matlab.ui.container.Panel
        Set3Button                    matlab.ui.control.Button
        Set2Button                    matlab.ui.control.Button
        Goto3Button                   matlab.ui.control.Button
        Goto2Button                   matlab.ui.control.Button
        Goto1Button                   matlab.ui.control.Button
        Set1Button                    matlab.ui.control.Button
        Pos3_Label                    matlab.ui.control.Label
        Pos2_Label                    matlab.ui.control.Label
        Pos1_Label                    matlab.ui.control.Label
        ArmServosasManualInputsPanel  matlab.ui.container.Panel
        GOButton                      matlab.ui.control.Button
        RESETButton                   matlab.ui.control.Button
        Joint2EditField               matlab.ui.control.NumericEditField
        Joint2EditFieldLabel          matlab.ui.control.Label
        Joint3EditField               matlab.ui.control.NumericEditField
        Joint3EditFieldLabel          matlab.ui.control.Label
        Joint1EditField               matlab.ui.control.NumericEditField
        Joint1EditFieldLabel          matlab.ui.control.Label
        ArmServosasSlidersPanel       matlab.ui.container.Panel
        Joint2Slider                  matlab.ui.control.Slider
        Joint2SliderLabel             matlab.ui.control.Label
        Joint3Slider                  matlab.ui.control.Slider
        Joint3SliderLabel             matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        
        jointAngles = [0 0 0];

        savedAngles1 = [0 0 0];
        savedAngles2 = [0 0 0];
        savedAngles3 = [0 0 0];
    end
    
    methods (Access = private)
        
        function updateArm(app)
            % Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(64, 0, 0, app.jointAngles(1));
            A2 = create_A_matrix(0, 0, -90, app.jointAngles(2));
            A3 = create_A_matrix(0, 140, 0, app.jointAngles(3));

            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1*A2;
            T0_3 = A1*A2*A3;

            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;

            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            
           % Update sliders
           app.Joint1Slider.Value = app.jointAngles(1);
           app.Joint2Slider.Value = app.jointAngles(2);
           app.Joint3Slider.Value = app.jointAngles(3);
            
           % Update edit fields
           app.Joint1EditField.Value = app.jointAngles(1);
           app.Joint2EditField.Value = app.jointAngles(2);
           app.Joint3EditField.Value = app.jointAngles(3);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.UIAxes.View = [90 -50];
            app.UIAxes.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.UIAxes.XLim = [-160 250];
            app.UIAxes.YLim = [-250 250];
            app.UIAxes.ZLim = [-22 22];

            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.UIAxes, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.UIAxes, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("AdeeptL2.stl", app.UIAxes, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("AdeeptL3.stl", app.UIAxes, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.updateArm()
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.updateArm()
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.updateArm()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.updateArm()
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           app.jointAngles(1) = app.Joint1EditField.Value;
           app.jointAngles(2) = app.Joint2EditField.Value;
           app.jointAngles(3) = app.Joint3EditField.Value;
           

           app.updateArm()
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
           app.jointAngles(1) = 0;
           app.jointAngles(2) = 0;
           app.jointAngles(3) = 0;

          app.updateArm()
        end

        % Button pushed function: Goto1Button
        function Goto1ButtonPushed(app, event)
            app.jointAngles(1) = app.savedAngles1(1);
            app.jointAngles(2) = app.savedAngles1(2);
            app.jointAngles(3) = app.savedAngles1(3);

            app.updateArm()
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.savedAngles1(1) = app.jointAngles(1);
            app.savedAngles1(2) = app.jointAngles(2);
            app.savedAngles1(3) = app.jointAngles(3);
            
            savedAngles1String = num2str(round(app.savedAngles1, 1));
            app.Pos1_Label.Text = savedAngles1String;
        end

        % Button pushed function: Goto2Button
        function Goto2ButtonPushed(app, event)
            app.jointAngles(1) = app.savedAngles2(1);
            app.jointAngles(2) = app.savedAngles2(2);
            app.jointAngles(3) = app.savedAngles2(3);

            app.updateArm()
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.savedAngles2(1) = app.jointAngles(1);
            app.savedAngles2(2) = app.jointAngles(2);
            app.savedAngles2(3) = app.jointAngles(3);
            
            savedAngles2String = num2str(round(app.savedAngles2, 1));
            app.Pos2_Label.Text = savedAngles2String;
        end

        % Button pushed function: Goto3Button
        function Goto3ButtonPushed(app, event)
            app.jointAngles(1) = app.savedAngles3(1);
            app.jointAngles(2) = app.savedAngles3(2);
            app.jointAngles(3) = app.savedAngles3(3);

            app.updateArm()
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.savedAngles3(1) = app.jointAngles(1);
            app.savedAngles3(2) = app.jointAngles(2);
            app.savedAngles3(3) = app.jointAngles(3);
            
            savedAngles3String = num2str(round(app.savedAngles3, 1));
            app.Pos3_Label.Text = savedAngles3String;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Adeept Arm')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ArmServosasSlidersPanel = uipanel(app.UIFigure);
            app.ArmServosasSlidersPanel.Title = 'Arm Servos as Sliders';
            app.Joint1SliderLabel = uilabel(app.ArmServosasSlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.ArmServosasSlidersPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.ArmServosasSlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.ArmServosasSlidersPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.ArmServosasSlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.ArmServosasSlidersPanel);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.ArmServosasManualInputsPanel = uipanel(app.UIFigure);
            app.ArmServosasManualInputsPanel.Title = 'Arm Servos as Manual Inputs';
            app.Joint1EditFieldLabel = uilabel(app.ArmServosasManualInputsPanel);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.ArmServosasManualInputsPanel, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.ArmServosasManualInputsPanel);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.ArmServosasManualInputsPanel, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.ArmServosasManualInputsPanel);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.ArmServosasManualInputsPanel, 'numeric');
            app.RESETButton = uibutton(app.ArmServosasManualInputsPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.GOButton = uibutton(app.ArmServosasManualInputsPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.SavedPositionsPanel = uipanel(app.UIFigure);
            app.SavedPositionsPanel.Title = 'Saved Positions';
            app.Pos1_Label = uilabel(app.SavedPositionsPanel);
            app.Pos1_Label.Text = '0 0 0';
            app.Pos2_Label = uilabel(app.SavedPositionsPanel);
            app.Pos2_Label.Text = '0 0 0';
            app.Pos3_Label = uilabel(app.SavedPositionsPanel);
            app.Pos3_Label.Text = '0 0 0';
            app.Set1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.Goto1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto1Button.ButtonPushedFcn = createCallbackFcn(app, @Goto1ButtonPushed, true);
            app.Goto1Button.Text = 'Go to 1';
            app.Goto2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto2Button.ButtonPushedFcn = createCallbackFcn(app, @Goto2ButtonPushed, true);
            app.Goto2Button.Text = 'Go to 2';
            app.Goto3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto3Button.ButtonPushedFcn = createCallbackFcn(app, @Goto3ButtonPushed, true);
            app.Goto3Button.Text = 'Go to 3';
            app.Set2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.Set3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end