classdef ME345_Exam1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ServerResponseTextArea       matlab.ui.control.TextArea
        ServerResponseTextAreaLabel  matlab.ui.control.Label
        PeriodmsEditField            matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel       matlab.ui.control.Label
        NumFlashesEditField          matlab.ui.control.NumericEditField
        NumFlashesEditFieldLabel     matlab.ui.control.Label
        FlashButton                  matlab.ui.control.StateButton
        LEDOffButton                 matlab.ui.control.StateButton
        LEDOnButton                  matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        led  = BuiltinLEDFrontend() % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LEDOnButton
        function LEDOnButtonValueChanged(app, event)
            response = app.led.on();
            app.ServerResponseTextArea.Value = response;
            
        end

        % Value changed function: LEDOffButton
        function LEDOffButtonValueChanged(app, event)
            response = app.led.off();
            app.ServerResponseTextArea.Value = response;
            
        end

        % Value changed function: FlashButton
        function FlashButtonValueChanged(app, event)
            count = app.NumFlashesEditField.Value;
            period = app.PeriodmsEditField.Value;

            response = app.led.flash(count,period);
            app.ServerResponseTextArea.Value = response;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDOnButton = uibutton(app.UIFigure, 'state');
            app.LEDOnButton.ValueChangedFcn = createCallbackFcn(app, @LEDOnButtonValueChanged, true);
            app.LEDOnButton.Text = 'LED On';
            app.LEDOffButton = uibutton(app.UIFigure, 'state');
            app.LEDOffButton.ValueChangedFcn = createCallbackFcn(app, @LEDOffButtonValueChanged, true);
            app.LEDOffButton.Text = 'LED Off';
            app.FlashButton = uibutton(app.UIFigure, 'state');
            app.FlashButton.ValueChangedFcn = createCallbackFcn(app, @FlashButtonValueChanged, true);
            app.FlashButton.Text = 'Flash';
            app.NumFlashesEditFieldLabel = uilabel(app.UIFigure);
            app.NumFlashesEditFieldLabel.Text = 'Num Flashes';
            app.NumFlashesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodmsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ServerResponseTextAreaLabel = uilabel(app.UIFigure);
            app.ServerResponseTextAreaLabel.Text = 'Server Response';
            app.ServerResponseTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ME345_Exam1_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end